classdef POM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EyeSegmentationAplication       matlab.ui.Figure
        OptionsPanel                    matlab.ui.container.Panel
        ChoosestartingpointButton       matlab.ui.control.Button
        StartsegmentationButton         matlab.ui.control.Button
        Make3DvisualizationButton       matlab.ui.control.Button
        SegmentationthresholdEditFieldLabel  matlab.ui.control.Label
        SegmentationthresholdEditField  matlab.ui.control.NumericEditField
        EndingthresholdEditFieldLabel   matlab.ui.control.Label
        EndingthresholdEditField        matlab.ui.control.NumericEditField
        ImagesPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        PathPanel                       matlab.ui.container.Panel
        LoadimagesButton                matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        myFolder % Description
        currentFileNumber
        numberOfFiles
        D                       %contains with all images
        positionX
        positionY
        mask
        
        segmentationThreshold = 30;
        endingThreshold = 20;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
app.myFolder = uigetdir('E:\studia\VI sem\Moje\POM\Projekt-POM\MR-głowa\STU0001');%('C:\', 'Choose directory');                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isfolder(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.dcm'); 

theFiles = dir(filePattern);

hWaitBar = waitbar(0,'Reading DICOM files');
for i=length(theFiles):-1:1
  file = fullfile(app.myFolder, theFiles(i).name);
%   app.D(:,:,i) = im2double(dicomread(file));                                                     %wczesniej było uint16
    %shrink region of interest
    I = double(dicomread(file));
    imSize = size(I,2);
    IROI = imcrop(I, [1 1 imSize imSize/3]);
    app.D(:,:,i) = IROI;
%app.D(:,:,i) = double(dicomread(file));
    waitbar((length(theFiles)-i)/length(theFiles))
end
delete(hWaitBar)
    I1 = app.D(:,:,1);
   imshow(I1,[],'Parent', app.UIAxes);
   drawnow; % Force display to update immediately.
  
  app.numberOfFiles = length(theFiles);
  app.currentFileNumber = 1;
  app.Label_2.Text = app.myFolder;
  
  formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,length(theFiles));
  app.Label.Visible = 'on';
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %Show previous image  
            app.currentFileNumber = app.currentFileNumber-1;
            if(app.currentFileNumber == 0)          
            imshow(app.D(:,:,app.numberOfFiles),[],'Parent', app.UIAxes);            
            app.currentFileNumber = app.numberOfFiles; 
            else             
            imshow(app.D(:,:, app.currentFileNumber),[],'Parent', app.UIAxes);
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,app.numberOfFiles);
  
  drawnow;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.currentFileNumber = app.currentFileNumber+1;
            if(app.currentFileNumber == app.numberOfFiles+1)
            imshow(app.D(:,:,1),[],'Parent', app.UIAxes);            
            app.currentFileNumber = 1;             
            else 
            imshow(app.D(:,:,app.currentFileNumber),[],'Parent', app.UIAxes);            
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,app.numberOfFiles);
  
  drawnow;
        end

        % Button pushed function: ChoosestartingpointButton
        function ChoosestartingpointButtonPushed(app, event)
            f = figure;
            imshow(app.D(:,:,app.currentFileNumber),[])
            
            [x,y]=ginput(1);
            hold on
            plot(x,y, 'xg', 'MarkerSize', 10);
            app.positionX = round(x);
            app.positionY = round(y);
            
            pause(2);
            close(f)
           
        end

        % Button pushed function: StartsegmentationButton
        function StartsegmentationButtonPushed(app, event)
            previousPixelIntensity = 0;
            se = strel('square',5); %element morfologiczny
%             threshold = 25;

            currentFileNo = app.currentFileNumber;
            for i = 1:1:app.numberOfFiles
            I = app.D(:,:, currentFileNo);
            
            Isegmented = regiongrowing(I,  app.positionY, app.positionX, app.segmentationThreshold);    %segmentation, x and y are switched! mistake in function
            Iclosed = imclose(Isegmented, se);                              %morfological close
            Iopen = imopen(Iclosed, se);                                    %morfological open
%             Isegmented2 = imfill(Isegmented,'holes');                                    
            
            app.mask(:,:,i) = Iopen;
            centroid = regionprops(Iclosed, 'Centroid');
%             app.mask(:,:,i) = Isegmented2;
%             centroid = regionprops(Isegmented2, 'Centroid');
            pixelIntensity = I(round(centroid.Centroid(2)), round(centroid.Centroid(1)));
                if abs(pixelIntensity -previousPixelIntensity) < app.endingThreshold || i ==1      
                    previousPixelIntensity = pixelIntensity;
                    currentFileNo = currentFileNo +1;
                    disp(i)
                    figure
                    imshow(app.mask(:,:,i))
                    pause(0.1)
                    
                else
                    break
                end
                
            end
            masktemp = app.mask;
            save('mask.mat', 'masktemp', '-mat');

        end

        % Button pushed function: Make3DvisualizationButton
        function Make3DvisualizationButtonPushed(app, event)
         
figure;
p = patch(isosurface(double(app.mask)));
p.FaceColor = 'red';
p.EdgeColor = 'none';
daspect([1,1,1]);
camlight; 
lighting phong   
pause
%stats = regionprops3(p,'all')
close all

% visualization = vol3d('cdata',app.mask,'texture','3D');
% view(3);  
% axis tight;  daspect([1 1 .4])
% alphamap('rampup');
% alphamap(.06 .* alphamap);


        end

        % Value changed function: SegmentationthresholdEditField
        function SegmentationthresholdEditFieldValueChanged(app, event)
            value = app.SegmentationthresholdEditField.Value;
            
            app.segmentationThreshold = value;
        end

        % Value changed function: EndingthresholdEditField
        function EndingthresholdEditFieldValueChanged(app, event)
            value = app.EndingthresholdEditField.Value;
            
            app.endingThreshold = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EyeSegmentationAplication = uifigure('Visible', 'off');
            app.EyeSegmentationAplication.Name = 'UI Figure';
            app.OptionsPanel = uipanel(app.EyeSegmentationAplication);
            app.OptionsPanel.Title = 'Options';
            app.ChoosestartingpointButton = uibutton(app.OptionsPanel, 'push');
            app.ChoosestartingpointButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosestartingpointButtonPushed, true);
            app.ChoosestartingpointButton.Text = 'Choose starting point';
            app.StartsegmentationButton = uibutton(app.OptionsPanel, 'push');
            app.StartsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsegmentationButtonPushed, true);
            app.StartsegmentationButton.Text = 'Start segmentation';
            app.Make3DvisualizationButton = uibutton(app.OptionsPanel, 'push');
            app.Make3DvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @Make3DvisualizationButtonPushed, true);
            app.Make3DvisualizationButton.Text = 'Make 3D visualization';
            app.SegmentationthresholdEditFieldLabel = uilabel(app.OptionsPanel);
            app.SegmentationthresholdEditFieldLabel.Text = 'Segmentation threshold';
            app.SegmentationthresholdEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SegmentationthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentationthresholdEditFieldValueChanged, true);
            app.SegmentationthresholdEditField.Value = 30;
            app.EndingthresholdEditFieldLabel = uilabel(app.OptionsPanel);
            app.EndingthresholdEditFieldLabel.Text = 'Ending threshold';
            app.EndingthresholdEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.EndingthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EndingthresholdEditFieldValueChanged, true);
            app.EndingthresholdEditField.Value = 20;
            app.ImagesPanel = uipanel(app.EyeSegmentationAplication);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.ImagesPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '1/100';
            app.PathPanel = uipanel(app.EyeSegmentationAplication);
            app.PathPanel.Title = 'Path';
            app.LoadimagesButton = uibutton(app.PathPanel, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Text = 'Load images';
            app.Label_2 = uilabel(app.PathPanel);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.EyeSegmentationAplication.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EyeSegmentationAplication)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EyeSegmentationAplication)
        end
    end
end